classdef IET_OPTIMIZATION_TOOL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OptimizationPanel            matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        SingleObjectiveTab           matlab.ui.container.Tab
        OptimizeButton               matlab.ui.control.Button
        ConstrainedLeastSquareLSsolutionLabel  matlab.ui.control.Label
        PsiInvPlot                   matlab.ui.control.UIAxes
        PsiErrorPlot                 matlab.ui.control.UIAxes
        CurrentsPlot                 matlab.ui.control.UIAxes
        SaveresultsButton            matlab.ui.control.Button
        LoadCoilsGeometryButton      matlab.ui.control.Button
        PrepareDataButton            matlab.ui.control.Button
        LoadEquilibriumButton        matlab.ui.control.Button
        psiBoundaryPlot              matlab.ui.control.UIAxes
        LoadEngineeringLimitsButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        OutputEquil
        coils % Description
        OptimDataIn
        %SOF
        OptimDataOut
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% IET (Inverse Equilibrium Tool) is a MATLAB computational tool constituted by three main modules, each with a dedicated GUI. It allows to compute the coil currents needed to obtain a predetermined plasma shape with defined plasma global parameters (i.e. total plasma current and total poloidal magnetic flux at the boundary) by solving a constrained minimization problem. 
% 
%     Copyright (C) 2019 Domenico Abate - domenico.abate@igi.cnr.it
% 
%     This program is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
% 
%     This program is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
% 
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.
            addpath([pwd '/IET_OPTIMIZATION_TOOL/funEquil/']);

        end

        % Button pushed function: LoadCoilsGeometryButton
        function LoadCoilsGeometryButtonPushed(app, event)
            uiopen('load');
            app.coils = coils;
        end

        % Button pushed function: PrepareDataButton
        function PrepareDataButtonPushed(app, event)
        [app.OptimDataIn] = prepareInverseEquilibrium(app);
            
        end

        % Button pushed function: LoadEquilibriumButton
        function LoadEquilibriumButtonPushed(app, event)
            uiopen('load');
            app.OutputEquil = OutputEquil;
            
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
             %app.SOF = app.ObjectivefunctionDropDown.Value;
            %app.OptimDataInput.SOF = app.SOF;
            [SOF_solution] = LS_solution(app);
            app.OptimDataOut.SOF_solution = SOF_solution;
        end

        % Button pushed function: LoadEngineeringLimitsButton
        function LoadEngineeringLimitsButtonPushed(app, event)
        uiopen('load');    
        app.OptimDataIn.LowerBounds = EngLimits.LB;
        app.OptimDataIn.UpperBounds = EngLimits.UB;
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
         OptimDataOut = app.OptimDataOut;
        uisave({'OptimDataOut'},'IETOptimizationToolOutput');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OptimizationPanel = uipanel(app.UIFigure);
            app.OptimizationPanel.Title = 'Optimization';
            app.TabGroup = uitabgroup(app.OptimizationPanel);
            app.SingleObjectiveTab = uitab(app.TabGroup);
            app.SingleObjectiveTab.Title = 'Single Objective';
            app.OptimizeButton = uibutton(app.SingleObjectiveTab, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.ConstrainedLeastSquareLSsolutionLabel = uilabel(app.SingleObjectiveTab);
            app.ConstrainedLeastSquareLSsolutionLabel.Text = 'Constrained Least Square (LS) solution';
            app.PsiInvPlot = uiaxes(app.SingleObjectiveTab);
            title(app.PsiInvPlot, 'Title')
            xlabel(app.PsiInvPlot, 'X')
            ylabel(app.PsiInvPlot, 'Y')
            app.PsiErrorPlot = uiaxes(app.SingleObjectiveTab);
            title(app.PsiErrorPlot, 'Title')
            xlabel(app.PsiErrorPlot, 'X')
            ylabel(app.PsiErrorPlot, 'Y')
            app.CurrentsPlot = uiaxes(app.SingleObjectiveTab);
            title(app.CurrentsPlot, 'Title')
            xlabel(app.CurrentsPlot, 'X')
            ylabel(app.CurrentsPlot, 'Y')
            app.SaveresultsButton = uibutton(app.SingleObjectiveTab, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Text = 'Save results';
            app.LoadCoilsGeometryButton = uibutton(app.UIFigure, 'push');
            app.LoadCoilsGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoilsGeometryButtonPushed, true);
            app.LoadCoilsGeometryButton.Text = 'Load Coils Geometry';
            app.PrepareDataButton = uibutton(app.UIFigure, 'push');
            app.PrepareDataButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareDataButtonPushed, true);
            app.PrepareDataButton.Text = 'Prepare Data';
            app.LoadEquilibriumButton = uibutton(app.UIFigure, 'push');
            app.LoadEquilibriumButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEquilibriumButtonPushed, true);
            app.LoadEquilibriumButton.Text = 'Load Equilibrium';
            app.psiBoundaryPlot = uiaxes(app.UIFigure);
            title(app.psiBoundaryPlot, 'Title')
            xlabel(app.psiBoundaryPlot, 'X')
            ylabel(app.psiBoundaryPlot, 'Y')
            app.LoadEngineeringLimitsButton = uibutton(app.UIFigure, 'push');
            app.LoadEngineeringLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEngineeringLimitsButtonPushed, true);
            app.LoadEngineeringLimitsButton.Text = 'Load Engineering Limits';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IET_OPTIMIZATION_TOOL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end